classdef visualize_light < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        EditMenu                matlab.ui.container.Menu
        NormalizeColorsMenu     matlab.ui.container.Menu
        RotateHorizontalMenu_2  matlab.ui.container.Menu
        RotateVerticalMenu      matlab.ui.container.Menu
        PreprocessingMenu       matlab.ui.container.Menu
        DecimateMenu            matlab.ui.container.Menu
        ZScoringMenu            matlab.ui.container.Menu
        ThresholdMenu           matlab.ui.container.Menu
        HistogramMatchingMenu   matlab.ui.container.Menu
        RemoveSmallObjectsMenu  matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        PreprocessTab           matlab.ui.container.Tab
        OpenFileButton          matlab.ui.control.Button
        XY                      matlab.ui.control.UIAxes
        MaxProjection           matlab.ui.control.UIAxes
        ZSliderLabel            matlab.ui.control.Label
        ZSlider                 matlab.ui.control.Slider
        RDropDownLabel          matlab.ui.control.Label
        RDropDown               matlab.ui.control.DropDown
        GDropDownLabel          matlab.ui.control.Label
        GDropDown               matlab.ui.control.DropDown
        BDropDownLabel          matlab.ui.control.Label
        BDropDown               matlab.ui.control.DropDown
        UpdateButton            matlab.ui.control.Button
        AutoIDDropDownLabel     matlab.ui.control.Label
        AutoIDDropDown          matlab.ui.control.DropDown
        IDEditField             matlab.ui.control.EditField
        AnnotateButton          matlab.ui.control.Button
        NeuronsListBoxLabel     matlab.ui.control.Label
        NeuronsListBox          matlab.ui.control.ListBox
        DetectNeuronsButton     matlab.ui.control.Button
        IDNeuronsButton         matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        UserUncertainty         matlab.ui.control.NumericEditField
        WCheckBox               matlab.ui.control.CheckBox
        ReconstructionTab       matlab.ui.container.Tab
        XYS                     matlab.ui.control.UIAxes
        ZSlider_2Label          matlab.ui.control.Label
        ZSliderS                matlab.ui.control.Slider
        MaxProjectionS          matlab.ui.control.UIAxes
        ReconstructButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        % The log of user activity for code evaluation/optimization.
        log
        
        % The default parameters for the program & matching pursuit.
        params
        mp_params
        
        % The location for the open image.
        file_path
        
        % The open image of the worm.
        % A pixel matrix of (x,y,z, color channel).
        image
        image_view % a subset of the image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)
        
        % Reconstructed image from gaussian approximations of neuron positions, shapes, & colors.
        % A reconstructed pixel matrix of (x,y,z, color channel).
        recon
        recon_view  % a subset of the reconstructed image that shows only what we're viewing, a matrix of (x,y,z, subset of color channels)
        
        % The shape of the neuron in the reconstruction.
        % A cell array of individual neuron shapes, each cell is a matrix of (x,y,z, color channel).
        recon_shapes
        
        % The neurons in the image.
        neurons = []
        
        % Which neuron is selected.
        selected_neuron
        
        % Mouse click states (a hack to detect double clicks).
        % Note: initialization is performed by startupFcn due construction issues.
        mouse_clicked;
        
        % Zoom percent for the keyboard shortcut.
        zoom_percent = 0.2;
        
        % Toggle image panning.
        is_panning = false;
        
        % Mouse position correction (the cursor is offset incorrectly).
        mouse_offset = [-1,1];
        
        % Neuron marker GUI attributes.
        % Note: initialization is performed by startupFcn due construction issues.
        neuron_marker;
        
        % Toggle neuron labels
        show_labels = false;
    end
    
    methods (Access = private)
        
        % Initialize the mouse click states (a hack to detect double clicks).
        % Note: initialization is performed by startupFcn due construction issues.
        function InitClickStates(app)
            app.mouse_clicked.double_click_delay = 0.3;
            app.mouse_clicked.add_neuron = false;
            app.mouse_clicked.del_neuron = false;
        end
        
        % Initialize the neuron marker GUI attributes.
        % Note: initialization is performed by startupFcn due construction issues.
        function InitNeuronMarker(app)
            app.neuron_marker.shape = 'c';
            app.neuron_marker.size.unselected = 60;
            app.neuron_marker.size.selected = 3 * app.neuron_marker.size.unselected;
            app.neuron_marker.line.unselected = 2;
            app.neuron_marker.line.selected = 2 * app.neuron_marker.line.unselected;
            app.neuron_marker.color.no_ID = [1,0,0]; % user added neuron but no ID yet or user annotated neuron as “?” (red)
            app.neuron_marker.color.user_ID = [0,1,0]; % user ID’d neuron as 100% correct (green)
            app.neuron_marker.color.high_prob_ID = [1,1,0]; % user ID’d neuron as low probability (yellow)
            app.neuron_marker.color.low_prob_ID = [1,0.5,0]; % model ID’d neuron (orange)
            app.neuron_marker.color.edge = [0,0,0];
        end
        
        % Draw the annotated image image (image volume & neuron markers).
        function DrawImageData(app)
            
            % Determine the channel=color assignments.
            color_indices = [str2num(app.RDropDown.Value), ...
                str2num(app.GDropDown.Value), ...
                str2num(app.BDropDown.Value)];
            
            % Are we displaying the white channel?
            w = app.WCheckBox.Value;
            if w && length(setdiff(1:size(app.image,4), color_indices)) ==1
                app.image_view = double(app.image(:,:,:,color_indices,:))/...
                    double(prctile(app.image(:), 99.9));
                wchannel = app.image(:,:,:,setdiff(1:size(app.image,4), color_indices));
                app.image_view = app.image_view .* double(repmat(wchannel/prctile(wchannel(:),90), [1,1,1,3]));
            else
                app.image_view = double(app.image(:,:,:,color_indices,:))/...
                    double(prctile(app.image(:), 99.9));
            end
            
            % Redraw the max projection.
            image(app.MaxProjection, squeeze(max(app.image_view,[],3)));
            
            % Redraw the Z-slice.
            app.ZSliderValueChanging(app.ZSlider);
        end
        
        % Add a neuron to a z-slice.
        function AddNeuron(app,im,hit)
            add_neuron = false; % add a neuron where the user clicked?
            
            switch hit.Button
                case 1
                    
                    % Check for a double click.
                    if app.mouse_clicked.add_neuron
                        %disp('double click');
                        add_neuron = true;
                        
                        % Single click.
                    else
                        app.mouse_clicked.add_neuron = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.add_neuron
                            %disp('single click');
                        end
                    end
                    app.mouse_clicked.add_neuron = false;
                    
                case 2
                    add_neuron = true;
            end
            
            % Add a neuron where the user clicked.
            if add_neuron
                
                % Where did the user click?
                mouse_pos = flip(hit.IntersectionPoint(1:2)) + app.mouse_offset;
                z = round(app.ZSlider.Value);
                point = [mouse_pos, z];
                
                % Create & add the neuron.
                neuron = [];
                neuron.mean(1,:) = point;
                neuron.color(1,:) = app.image(round(neuron.mean(1)),round(neuron.mean(2)),round(neuron.mean(3)),:);
                neuron.baseline(1,:) = mean(app.neurons.baseline, 1);
                neuron.cov(1,:,:) = mean(app.neurons.cov, 1);
                neuron.ids{1} = 'Unknown';
                app.neurons = union_sp(app.neurons, neuron);
                
                % Draw the new neuron marker.
                sc = app.XY.Children(1);
                if size(sc.CData,1)>1
                    set(sc,'XData',[sc.XData, point(2)], ...
                        'YData',[sc.YData, point(1)], ...
                        'CData',[sc.CData; [1,1,1]]);
                else
                    set(sc,'XData',[sc.XData, point(2)], ...
                        'YData',[sc.YData, point(1)]);
                end
            end
        end
        
        % Draw the neurons in this z-slice.
        function DrawZSlice(app)
            
            % Where are we?
            cla(app.XY);
            z = app.ZSlider.Value;
            xy = squeeze(app.image_view(:,:,z,:,:));
            im = image(xy, 'Parent', app.XY); hold(app.XY, 'on');
            im.ButtonDownFcn = {@app.AddNeuron};
            
            % Redraw the neurons in this z slice.
            if ~isempty(app.neurons) && ~isempty(app.neurons(1))
                
                % Which neurons belong in this z-slice?
                neuron_locations = app.neurons(1).mean;
                xygt = neuron_locations(neuron_locations(:,3)>z-0.5 & neuron_locations(:,3)<z+0.5, [2,1]);
                
                % Are we showing the neuron annotations?
                if isfield(app.neurons, 'annotations') && app.show_labels
                    annot = app.neurons.annotations;
                    annot = annot(neuron_locations(:,3)>z-0.5 & neuron_locations(:,3)<z+0.5);
                    text(app.XY, xygt(:, 1)+4, xygt(:, 2)+4, annot, ...
                        'Color', 'yellow', 'FontSize', 10, 'FontWeight', 'bold');
                end
                
                % Draw the neuron markers.
                neuron_marker_plot = scatter(app.XY, xygt(:, 1), xygt(:, 2), ...
                    app.neuron_marker.size.unselected, app.neuron_marker.shape, ...
                    'MarkerEdgeColor', app.neuron_marker.color.edge, ...
                    'MarkerFaceColor', app.neuron_marker.color.no_ID, ...
                    'LineWidth', app.neuron_marker.line.unselected);
                neuron_marker_plot.ButtonDownFcn = {@app.NeuronClicked};
            end
        end
        
        % Select or delete neurons where the user clicked.
        function NeuronClicked(app, im, hit)
            select_neuron = false; % select the neuron where the user clicked?
            delete_neuron = false; % delete the neuron where the user clicked?
            
            % Where did the user click?
            mouse_pos = flip(hit.IntersectionPoint(1:2));
            z = round(app.ZSlider.Value);
            point = [mouse_pos, z];
            
            % Find the closest neuron to the mouse click.
            [~, neuron_i] = min(mean((app.neurons(1).mean-point).^2,2));
            
            switch hit.Button
                case 1 % left click
                    
                    % Check for a double click.
                    if app.mouse_clicked.del_neuron
                        delete_neuron = true;
                        
                        % Single click.
                    else
                        app.mouse_clicked.del_neuron = true;
                        pause(app.mouse_clicked.double_click_delay);
                        if app.mouse_clicked.del_neuron
                            select_neuron = true;
                        end
                    end
                    app.mouse_clicked.del_neuron = false;
                    
                case 3 % right click
                    delete_neuron = true;
            end
            
            % Select the neuron.
            if select_neuron
                
                % Translate the click to a neuron.
                neuron_plot_i = find(sum([im.YData; im.XData]' == app.neurons(1).mean(neuron_i,[1,2]),2));
                SelectNeuron(app, im, neuron_plot_i, neuron_i);
                
                % Delete the neuron from the list.
            elseif delete_neuron
                
                % Translate the click to a neuron.
                neuron_plot_i = find(sum([im.YData; im.XData]' == app.neurons(1).mean(neuron_i,[1,2]),2));
                DeleteNeuron(app, im, neuron_plot_i, neuron_i);
            end
        end
        
        % Select a neuron from the z-slice image.
        function SelectNeuron(app, im, neuron_plot_i, neuron_i)
            
            % Update the neuron plot marker to show that it's selected.
            neuron_plot_i = find(sum([im.YData; im.XData]' == app.neurons(1).mean(neuron_i,[1,2]),2));
            num_z_neurons = length(im.XData);
            %im.CData = [repmat(app.neuron_marker.color.unselected, neuron_plot_i-1, 1); ...
            %    app.neuron_marker.color.selected; ...
            %    repmat(app.neuron_marker.color.unselected, num_z_neurons-neuron_plot_i, 1)];
            im.SizeData = [repmat(app.neuron_marker.size.unselected, neuron_plot_i-1, 1); ...
                app.neuron_marker.size.selected; ...
                repmat(app.neuron_marker.size.unselected, num_z_neurons-neuron_plot_i, 1)];
            im.CData = [repmat(app.neuron_marker.color.no_ID, neuron_plot_i-1, 1); ...
                app.neuron_marker.color.user_ID; ...
                repmat(app.neuron_marker.color.no_ID, num_z_neurons-neuron_plot_i, 1)];
            im.MarkerFaceColor = 'flat';
            
            % Update the dropdown menu to show the selected neuron.
            app.selected_neuron = neuron_i;
            if isfield(app.neurons, 'ids')
                values = app.neurons.ids_prob(neuron_i,:);
                probs = app.neurons.assignment_prob_probs(neuron_i,:);
                str = strcat(arrayfun(@(x) num2str(x, '%.2f'), probs, 'UniformOutput', 0), ',', values);
                app.AutoIDDropDown.Items = str;
                app.IDEditField.Value = app.neurons.ids{neuron_i};
            end
        end
        
        % Delete a neuron from the z-slice image.
        function results = DeleteNeuron(app,im, neuron_plot_i, neuron_i)
            
            % Delete the neuron marker from the plot.
            if size(im.CData,1)>1
                set(im,'XData',[im.XData(1:neuron_plot_i-1),im.XData(neuron_plot_i+1:end)], ...
                    'YData',[im.YData(1:neuron_plot_i-1),im.YData(neuron_plot_i+1:end)], ...
                    'CData',[im.CData(1:neuron_plot_i-1,:);im.CData(neuron_plot_i+1:end,:)]);
            else
                set(im,'XData',[im.XData(1:neuron_plot_i-1),im.XData(neuron_plot_i+1:end)], ...
                    'YData',[im.YData(1:neuron_plot_i-1),im.YData(neuron_plot_i+1:end)]);
                
            end
            
            % Delete the neuron from the GUI list.
            if isfield(app.neurons, 'ids')
                uncertain_idx = app.neurons.rank_uncertain(neuron_i);
                app.NeuronsListBox.Items = {app.NeuronsListBox.Items{1:uncertain_idx-1}, app.NeuronsListBox.Items{uncertain_idx+1:end}};
            end
            
            % Delete the neuron from the master list.
            app.neurons = sub_sp(app.neurons, [1:neuron_i-1,neuron_i+1:size(app.neurons.mean,1)]);
            
            % WTF is this???
            if isfield(app.neurons, 'ids')
                app.neurons.rank_uncertain(app.neurons.rank_uncertain > uncertain_idx) = app.neurons.rank_uncertain(app.neurons.rank_uncertain > uncertain_idx) - 1;
                %app.NeuronsListBoxValueChanged(app.NeuronsListBox);
            end
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Setup the path for program execution.
            addpath(genpath('.'));
            app.params = get_params();
            
            % Resize the figure to 3/4 screen size.
            win_scale = [1/16, 7/8]; % main window scale
            figure_size = get(0, 'Screensize');
            figure_size(1:2) = round(figure_size(1:2) + figure_size(3:4) .* win_scale(1));
            figure_size(3:4) = round(figure_size(3:4) .* win_scale(2));
            drawnow; % need to draw before resizing
            app.UIFigure.Position = figure_size;
            
            
            % Log the change.
            app.log = TraceHistory.Instance;
            app.log.setup({'startupFcn'});
            
            % Initialize the structured properties.
            InitClickStates(app);
            InitNeuronMarker(app);
        end

        % Callback function: OpenFileButton, OpenMenu
        function OpenFile(app, event)
            
            % Ask the user which file they want.
            [name, path, ~] = uigetfile(app.params.default_data_directory);
            if name == 0
                return; % user cancelled
            end
            app.file_path = [path, name];
            [path, name, ext] = fileparts([path, name]);
            
            % Load a Zeiss CZI file.
            if strcmp(ext, '.czi')
                [video, options] = load_czi(app.file_path);
                
                tiff = [];
                tiff.video = uint16(video);
                tiff.options = options;
                
                save([app.file_path(1:end-3), 'mat'], 'tiff', '-v7.3');
            end
            
            file = matfile([path,'/',name,'.mat']);
            
            try
                video = file.video;
            catch
                tiff = file.tiff;
                video = tiff.video;
                clear tiff;
            end
            
            try
                app.image = video(:,:,:,[1,2,4,5]);
            catch
                app.image = video;
            end
            
            
            app.RDropDown.Items = arrayfun(@num2str, 1:size(app.image, 4), 'UniformOutput', false);
            app.GDropDown.Items = arrayfun(@num2str, 1:size(app.image, 4), 'UniformOutput', false);
            app.BDropDown.Items = arrayfun(@num2str, 1:size(app.image, 4), 'UniformOutput', false);
            
            try
                app.RDropDown.Value = '4';
                app.GDropDown.Value = '3';
                app.BDropDown.Value = '1';
            catch
            end
            
            % Restrict the slider to the z stack.
            app.ZSlider.Limits = [1, size(app.image, 3)];
            app.ZSlider.MajorTicks = 1:size(app.image, 3);
            app.ZSlider.MajorTickLabelsMode = 'auto';


            app.DrawImageData();
            
            % Setup the max projection.
            daspect(app.XY,[1 1 1]);
            daspect(app.MaxProjection,[1 1 1]);
            daspect(app.MaxProjectionS,[1 1 1]);
            daspect(app.XYS,[1 1 1]);
            axis(app.MaxProjection, 'off');
            axis(app.MaxProjectionS, 'off');
            
            % Go to the middle.
            zEvent.Value = round(size(app.image,3) / 2);
            ZSliderValueChanged(app, zEvent);
        end

        % Menu selected function: DecimateMenu
        function DecimateButtonPushed(app, event)
            prompt = {'Enter the decimation factor:','Enter the method (linear/nearest/cubic):'};
            title = 'Decimation Parameters';
            dims = [1 35];
            definput = {'2','linear'};
            answer = inputdlg(prompt,title,dims,definput);
            
            app.image = decimate_frame(app.image, round(size(app.image)/str2double(answer{1})), answer{2});
            
            app.ZSlider.Limits = [1, size(app.image, 3)];
            app.ZSliderS.Limits = [1, size(app.image, 3)];
            if ~isempty(app.neurons)
                app.neurons.mean = app.neurons.mean/str2double(answer{1});
                app.neurons.cov = app.neurons.cov/str2double(answer{1});
            end
            
            app.DrawImageData();
            
        end

        % Menu selected function: ThresholdMenu
        function AdThreshButtonPushed(app, event)
            prompt = {'Enter the method (adaptive/fixed/otsu):', 'Enter the parameter for the model:'};
            title = 'Thresholding Parameters';
            dims = [1 35];
            definput = {'adaptive', '0.9'};
            answer = inputdlg(prompt,title,dims,definput);
            
            app.image = threshold_frame(app.image, answer{1}, str2double(answer{2}));
            app.DrawImageData();
        end

        % Menu selected function: HistogramMatchingMenu
        function HistMatchButtonPushed(app, event)
            app.image = histmatch_frame(double(app.image));
            app.DrawImageData();
        end

        % Menu selected function: RemoveSmallObjectsMenu
        function RmSmallObjButtonPushed(app, event)
            prompt = {'Enter the minimum size of components:'};
            title = 'Remove Small Objects Parameters';
            dims = [1 35];
            definput = {'50'};
            answer = inputdlg(prompt,title,dims,definput);
            
            app.image = rm_smallobj_frame(app.image, str2num(answer{1}));
            app.DrawImageData();
        end

        % Callback function
        function SaveLabelButtonPushed(app, event)
            app.saved_labels{end + 1} = app.LabelEditField.Value;
            app.saved_datasets{end + 1} = app.image;
            
            app.SavedLabelDropDown.Items = app.saved_labels;
            app.SavedLabelDropDown_2.Items = app.saved_labels;
        end

        % Value changing function: ZSlider
        function ZSliderValueChanging(app, event)
            
            % Snap the slider into place.
            z = round(event.Value);
            app.ZSlider.Value = z;
            
            % Draw the neuorns in this z-slice.
            DrawZSlice(app);
        end

        % Value changing function: ZSliderS
        function ZSliderSValueChanging(app, event)
            
            z = round(event.Value); cla(app.XYS);
            c = app.neurons(1).mean;
            frame = squeeze(app.recon_view(:,:,:,:,1));
            view = double(frame)/double(prctile(app.image_view(:), 99.9));
            xy = squeeze(view(:,:,z,:));
            image(xy, 'Parent', app.XYS); hold(app.XYS, 'on');
            xygt = c(c(:, 3) > z - 0.5 & c(:, 3) < z + 0.5, [2, 1]);
            scatter(app.XYS, xygt(:, 1), xygt(:, 2), ...
                app.neuron_marker.size, app.neuron_marker.shape, ...
                'MarkerEdgeColor', app.neuron_marker.color.edge, ...
                'MarkerFaceColor', app.neuron_marker.color.unselected, ...
                'LineWidth', app.neuron_marker.line);
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.DrawImageData();
        end

        % Button pushed function: DetectNeuronsButton
        function DetectNeuronsButtonPushed(app, event)
            
            [path, name, ~] = fileparts(app.file_path);
            try
                load([path, '/', name, '_sp', '.mat']);
                answer = questdlg(['Load already existing (k=', num2str(mp_params.k), ') superpixels?'], ...
                   	'File exists', 'Load', 'Overwrite', 'Cancel');
                if strcmp(answer, 'Overwrite')
                    throw(exception);
                end
            catch
                prompt = {'Enter the window size:','Enter the trunction value:', 'Enter the number of neurons:'};
                title = 'MP Parameters';
                dims = [1 35];
                definput = {app.params.mp.default_window,app.params.mp.default_trunc,app.params.mp.default_k};
                answer = inputdlg(prompt,title,dims,definput);
                
                mp_params.hnsz = str2num(answer{1});
                mp_params.trunc = str2num(answer{2});
                mp_params.k = str2num(answer{3});
                mp_params.bca_iter = 0;
                
                filter = get_filter(mp_params.hnsz, [6,6,6], 0);
                sp = matching_pursuit_gaussian_two_step(app.image, filter, mp_params.k, mp_params.trunc);
                save([path, '/', name, '_sp', '.mat'], 'sp', 'mp_params');
                mp_params.bca_iter = 2;
                sp = matching_pursuit_bca(app.image, sp, mp_params.hnsz, mp_params.trunc, mp_params.bca_iter);
                save([path, '/', name, '_sp', '.mat'], 'sp', 'mp_params');
            end
            
            app.mp_params = mp_params;
            app.neurons = sp;
            
            app.DrawImageData();
            
        end

        % Button pushed function: AnnotateButton
        function AnnotateButtonPushed(app, event)
            if ~isfield(app.neurons, 'annotations')
                app.neurons.annotations = cell(size(app.neurons.mean,1),1);
            end
            app.neurons.annotations{app.selected_neuron} = app.IDEditField.Value;
            app.neurons.annotation_uncertainty(app.selected_neuron) = app.UserUncertainty.Value;
            
            try
                app.neurons.LL(app.selected_neuron,:)= -100000;
                
                if ~strcmp(app.IDEditField.Value, 'NaN')
                    app.neurons.LL(app.selected_neuron, strcmp(app.neurons.id_neurons, app.IDEditField.Value)) = 0;
                end
                
                app.neurons = compute_assignments(app.neurons);
                app.neurons = find_ids(app.neurons);
                
                
                strs = arrayfun(@(x) strjoin(app.neurons.ids_prob(x,:), ','), 1:size(app.neurons.ids_prob,1), 'UniformOutput', 0)';
                pt(app.neurons.rank_uncertain) = 1:length(app.neurons.rank_uncertain);
                app.NeuronsListBox.Items = strcat(app.neurons.ids(pt),'----', strs(pt));
                
                app.NeuronsListBoxValueChanged(app.NeuronsListBox);
            catch
                
            end
            
        end

        % Callback function
        function RotationValueChanged(app, event)
            value = app.Rotation.Value;
            R = makehgtform('xrotate', value);
            image = [];
            sz=size(app.image);
            Rin = imref3d(sz(1:3));
            Rin.XWorldLimits = Rin.XWorldLimits-2*mean(Rin.XWorldLimits);
            Rin.YWorldLimits = Rin.YWorldLimits-2*mean(Rin.YWorldLimits);
            Rin.ZWorldLimits = Rin.ZWorldLimits-2*mean(Rin.ZWorldLimits);
            
            for c=1:size(app.image,4)
                image(:,:,:,c) = imwarp(app.image(:,:,:,c,:),Rin, affine3d(R),'cubic');
            end
            
            if ~isempty(app.neurons)
                app.neurons.mean(:,[2,1,3]) = (app.neurons.mean(:,[2,1,3])-(sz([2,1,3])+1))*R(1:3,1:3)+(sz([2,1,3])+1);
                for i=1:size(app.neurons.mean,1)
                    app.neurons.cov(i,[2,1,3],[2,1,3])=R(1:3,1:3)*squeeze(app.neurons.cov(i,[2,1,3],[2,1,3]))*R(1:3,1:3)';
                end
            end
            app.image = image;
            
            
            app.XSlider.Limits = [1, size(app.image, 1)];
            app.YSlider.Limits = [1, size(app.image, 2)];
            app.ZSlider.Limits = [1, size(app.image, 3)];
            
            app.XSliderS.Limits = [1, size(app.image, 1)];
            app.YSliderS.Limits = [1, size(app.image, 2)];
            app.ZSliderS.Limits = [1, size(app.image, 3)];
            
            app.Rotation.Value = 0;
            app.DrawImageData();
            
        end

        % Button pushed function: IDNeuronsButton
        function IDNeuronsButtonPushed(app, event)
            try
                app.neurons.ids;
                answer = questdlg('Load already existing IDs?', ...
                   	'File exists', 'Load', 'Overwrite', 'Cancel');
                if strcmp(answer, 'Overwrite')
                    throw(exception);
                end
            catch
                prompt = {'Head or Tail:', 'Method (rwc/mog):'};
                title = 'AutoID Parameters';
                dims = [1 35];
                definput = {'tail', 'rwc'};
                answer = inputdlg(prompt,title,dims,definput);
                app.neurons.compartment = answer{1};
                
                app.neurons = auto_id(app.neurons, answer{2});
                app.neurons = compute_assignments(app.neurons);
                app.neurons = find_ids(app.neurons);
            end
            
            strs = arrayfun(@(x) strjoin(app.neurons.ids_prob(x,:), ','), 1:size(app.neurons.ids_prob,1), 'UniformOutput', 0)';
            pt(app.neurons.rank_uncertain) = 1:length(app.neurons.rank_uncertain);
            app.NeuronsListBox.Items = strcat(app.neurons.ids(pt),'----', strs(pt));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [path, name, ~] = fileparts(app.file_path);
            sp = app.neurons;
            mp_params = app.mp_params;
            save([path, '/', name, '_sp', '.mat'], 'sp', 'mp_params');
        end

        % Menu selected function: NormalizeColorsMenu, ZScoringMenu
        function ZScoringMenuSelected(app, event)
            
            app.image = zscore_frame(double(app.image));
            app.DrawImageData();
        end

        % Menu selected function: RotateHorizontalMenu_2
        function RotateHorizontal(app, event)
            app.image = app.image(:,end:-1:1,end:-1:1,:,:);
            if ~isempty(app.neurons)
                app.neurons.mean = [app.neurons.mean(:,1), ...
                    size(app.image,2)-app.neurons.mean(:,2), ...
                    size(app.image,3)-app.neurons.mean(:,3)];
                % Multiply cov by rotation matrix
            end
            app.DrawImageData();
        end

        % Menu selected function: RotateVerticalMenu
        function RotateVertical(app, event)
            app.image = app.image(end:-1:1,:,end:-1:1,:,:);
            if ~isempty(app.neurons)
                app.neurons.mean = [size(app.image,1)-app.neurons.mean(:,1), ...
                    app.neurons.mean(:,2), ...
                    size(app.image,3)-app.neurons.mean(:,3)];
                % Multiply cov by rotation matrix
            end
            app.DrawImageData();
        end

        % Value changed function: AutoIDDropDown
        function AutoIDDropDownValueChanged(app, event)
            
            value = app.AutoIDDropDown.Value;
            values = strsplit(value,',');
            
            app.IDEditField.Value = values{2};
            app.UserUncertainty.Value = str2num(values{1});
            
        end

        % Value changed function: NeuronsListBox
        function NeuronsListBoxValueChanged(app, event)
            value = app.NeuronsListBox.Value;
            index = (app.neurons.rank_uncertain == find(strcmp(app.NeuronsListBox.Items, value),1));
            pos = app.neurons.mean(index,:);
            app.ZSlider.Value=pos(3);
            app.ZSliderValueChanging(app.ZSlider);
            hit = [];
            hit.Button = 1;
            hit.IntersectionPoint = pos([2,1,3]);
            app.NeuronClicked(app.XY.Children(1), hit);
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                
                % Go down in the z stack.
                case 'rightarrow'
                    if app.ZSlider.Value < app.ZSlider.Limits(2)
                        zEvent.Value = app.ZSlider.Value + 1;
                        ZSliderValueChanged(app, zEvent);
                    end
                    %disp(key);
                    
                    % Go up in the z stack.
                case 'leftarrow'
                    if app.ZSlider.Value > 1
                        zEvent.Value = app.ZSlider.Value - 1;
                        ZSliderValueChanged(app, zEvent);
                    end
                    %disp(key);
                    
                case 'uparrow' % Zoom in.
                    zoom(app.XY, 1 + app.zoom_percent);
                    
                case 'downarrow' % Zoom out.
                    zoom(app.XY, 1 - app.zoom_percent);
                    
                case {'p','P'} % Toggle image panning.
                    
                    % Toggle image panning.
                    %if app.is_panning
                    %    pan(app.XY,'off');
                    %    app.is_panning = false;
                    %else
                    %    pan(app.XY,'on');
                    %    app.is_panning = true;
                    %end
                    
                case {'r','R'} % Reset the figure to center.
                    %app.XY.XAxisLocation = 'bottom';
                    %app.XY.YAxisLocation = 'left';
                    
                case {'x','X'} % Switch mouse to crosshairs.
                    %zoom(app.XY,'on');
                    %pause(0.2);
                    %zoom(app.XY,'off');
                case {'i','I'}
                    app.show_labels = ~app.show_labels;
                    ZSliderValueChanged(app, app.ZSlider);
                otherwise
                    disp(['other "' key '"']);
            end
            
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            ZSliderValueChanging(app, event);
        end

        % Value changed function: WCheckBox
        function WCheckBoxValueChanged(app, event)
            app.DrawImageData();
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            
            %             sz = size(app.recon);
            %             app.recon = app.recon - placement(sz(1:3), round(app.neurons.mean(neuron_i,:)), app.recon_shapes{neuron_i});
            %             app.recon_shapes = {app.recon_shapes{1:neuron_i-1}, app.recon_shapes{neuron_i+1:end}};
            sz = size(app.image);
            [app.recon, app.recon_shapes] = sp2video(app.neurons, [sz(1:3),size(app.neurons.color,2),1], app.mp_params.hnsz, app.mp_params.trunc);
            
            color_indices = [str2num(app.RDropDown.Value), ...
                str2num(app.GDropDown.Value), ...
                str2num(app.BDropDown.Value)];
            
            w = app.WCheckBox.Value;
            if w && length(setdiff(1:size(app.image,4), color_indices)) ==1
                app.recon_view = double(app.recon(:,:,:,color_indices,:))/...
                    double(prctile(app.recon(:), 99.9));
                wchannel = app.recon(:,:,:,setdiff(1:size(app.image,4), color_indices));
                app.image_view = app.recon_view .* double(repmat(wchannel/prctile(wchannel(:),90), [1,1,1,3]));
                
                app.recon_view = app.recon(:,:,:,color_indices,:)/...
                    double(prctile(app.image(:), 99.9));
            else
                app.recon_view = app.recon(:,:,:,color_indices,:)/...
                    double(prctile(app.image(:), 99.9));
            end
            
            
            app.ZSliderSValueChanging(app.ZSlider);
            image(app.MaxProjectionS, squeeze(max(app.recon_view,[],3)));
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                log = app.log;
                [path, name, ~] = fileparts(app.file_path);
                mkdir([path, '/logs/']);
                save([path, '/logs/', name, '_', datestr(datetime('now')), '.mat'], 'log');
            catch
                warning('Log is not saved');
            end
            delete(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenMenu.Text = 'Open';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.NormalizeColorsMenu = uimenu(app.EditMenu);
            app.NormalizeColorsMenu.MenuSelectedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.NormalizeColorsMenu.Text = 'Normalize Colors';
            app.RotateHorizontalMenu_2 = uimenu(app.EditMenu);
            app.RotateHorizontalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RotateHorizontal, true);
            app.RotateHorizontalMenu_2.Text = 'Rotate Horizontal';
            app.RotateVerticalMenu = uimenu(app.EditMenu);
            app.RotateVerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @RotateVertical, true);
            app.RotateVerticalMenu.Text = 'Rotate Vertical';
            app.PreprocessingMenu = uimenu(app.UIFigure);
            app.PreprocessingMenu.Text = 'Preprocessing';
            app.DecimateMenu = uimenu(app.PreprocessingMenu);
            app.DecimateMenu.MenuSelectedFcn = createCallbackFcn(app, @DecimateButtonPushed, true);
            app.DecimateMenu.Text = 'Decimate';
            app.ZScoringMenu = uimenu(app.PreprocessingMenu);
            app.ZScoringMenu.MenuSelectedFcn = createCallbackFcn(app, @ZScoringMenuSelected, true);
            app.ZScoringMenu.Text = 'Z Scoring';
            app.ThresholdMenu = uimenu(app.PreprocessingMenu);
            app.ThresholdMenu.MenuSelectedFcn = createCallbackFcn(app, @AdThreshButtonPushed, true);
            app.ThresholdMenu.Text = 'Threshold';
            app.HistogramMatchingMenu = uimenu(app.PreprocessingMenu);
            app.HistogramMatchingMenu.MenuSelectedFcn = createCallbackFcn(app, @HistMatchButtonPushed, true);
            app.HistogramMatchingMenu.Text = 'Histogram Matching';
            app.RemoveSmallObjectsMenu = uimenu(app.PreprocessingMenu);
            app.RemoveSmallObjectsMenu.MenuSelectedFcn = createCallbackFcn(app, @RmSmallObjButtonPushed, true);
            app.RemoveSmallObjectsMenu.Text = 'Remove Small Objects';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PreprocessTab = uitab(app.TabGroup);
            app.PreprocessTab.Title = 'Preprocess';
            app.OpenFileButton = uibutton(app.PreprocessTab, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFile, true);
            app.OpenFileButton.Text = 'Open File';
            app.XY = uiaxes(app.PreprocessTab);
            app.MaxProjection = uiaxes(app.PreprocessTab);
            app.ZSliderLabel = uilabel(app.PreprocessTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.PreprocessTab);
            app.ZSlider.Limits = [1 30];
            app.ZSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.ZSlider.MajorTickLabels = {'', ''};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.ValueChangingFcn = createCallbackFcn(app, @ZSliderValueChanging, true);
            app.ZSlider.MinorTicks = [];
            app.ZSlider.Value = 1;
            app.RDropDownLabel = uilabel(app.PreprocessTab);
            app.RDropDownLabel.Text = 'R';
            app.RDropDown = uidropdown(app.PreprocessTab);
            app.RDropDown.Items = {'1', '2', '3'};
            app.RDropDown.Value = '1';
            app.GDropDownLabel = uilabel(app.PreprocessTab);
            app.GDropDownLabel.Text = 'G';
            app.GDropDown = uidropdown(app.PreprocessTab);
            app.GDropDown.Items = {'1', '2', '3'};
            app.GDropDown.Value = '2';
            app.BDropDownLabel = uilabel(app.PreprocessTab);
            app.BDropDownLabel.Text = 'B';
            app.BDropDown = uidropdown(app.PreprocessTab);
            app.BDropDown.Items = {'1', '2', '3'};
            app.BDropDown.Value = '3';
            app.UpdateButton = uibutton(app.PreprocessTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.AutoIDDropDownLabel = uilabel(app.PreprocessTab);
            app.AutoIDDropDownLabel.Text = 'AutoID';
            app.AutoIDDropDown = uidropdown(app.PreprocessTab);
            app.AutoIDDropDown.Items = {};
            app.AutoIDDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoIDDropDownValueChanged, true);
            app.AutoIDDropDown.Value = {};
            app.IDEditField = uieditfield(app.PreprocessTab, 'text');
            app.AnnotateButton = uibutton(app.PreprocessTab, 'push');
            app.AnnotateButton.ButtonPushedFcn = createCallbackFcn(app, @AnnotateButtonPushed, true);
            app.AnnotateButton.Text = 'Annotate';
            app.NeuronsListBoxLabel = uilabel(app.PreprocessTab);
            app.NeuronsListBoxLabel.Text = 'Neurons';
            app.NeuronsListBox = uilistbox(app.PreprocessTab);
            app.NeuronsListBox.Items = {};
            app.NeuronsListBox.ValueChangedFcn = createCallbackFcn(app, @NeuronsListBoxValueChanged, true);
            app.NeuronsListBox.Value = {};
            app.DetectNeuronsButton = uibutton(app.PreprocessTab, 'push');
            app.DetectNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @DetectNeuronsButtonPushed, true);
            app.DetectNeuronsButton.Text = 'Detect Neurons';
            app.IDNeuronsButton = uibutton(app.PreprocessTab, 'push');
            app.IDNeuronsButton.ButtonPushedFcn = createCallbackFcn(app, @IDNeuronsButtonPushed, true);
            app.IDNeuronsButton.Text = 'ID Neurons';
            app.SaveButton = uibutton(app.PreprocessTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.UserUncertainty = uieditfield(app.PreprocessTab, 'numeric');
            app.WCheckBox = uicheckbox(app.PreprocessTab);
            app.WCheckBox.ValueChangedFcn = createCallbackFcn(app, @WCheckBoxValueChanged, true);
            app.WCheckBox.Text = 'W';
            app.ReconstructionTab = uitab(app.TabGroup);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.XYS = uiaxes(app.ReconstructionTab);
            app.ZSlider_2Label = uilabel(app.ReconstructionTab);
            app.ZSlider_2Label.Text = 'Z';
            app.ZSliderS = uislider(app.ReconstructionTab);
            app.ZSliderS.Limits = [1 100];
            app.ZSliderS.MajorTicks = [];
            app.ZSliderS.MajorTickLabels = {};
            app.ZSliderS.ValueChangingFcn = createCallbackFcn(app, @ZSliderSValueChanging, true);
            app.ZSliderS.MinorTicks = [];
            app.ZSliderS.Value = 1;
            app.MaxProjectionS = uiaxes(app.ReconstructionTab);
            app.ReconstructButton = uibutton(app.ReconstructionTab, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
        end
    end

    methods (Access = public)

        % Construct app
        function app = visualize_light

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end